classdef Hydra_ROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FilePathEditFieldLabel  matlab.ui.control.Label
        FilePathEditField       matlab.ui.control.EditField
        LoadFrameButton         matlab.ui.control.Button
        CopyfolderpathandclickLoadFrameLabel  matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        InitializationTab       matlab.ui.container.Tab
        DrawROIsPanel           matlab.ui.container.Panel
        AddROIButton            matlab.ui.control.Button
        FirstcopyfolderpathandclickLoadFrameLabel  matlab.ui.control.Label
        AnnotateHydraPanel      matlab.ui.container.Panel
        HydraDropDownLabel      matlab.ui.control.Label
        AnnotateHydraDropDown   matlab.ui.control.DropDown
        AnnotateButton          matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        UITableROI              matlab.ui.control.Table
        UITableAnnotate         matlab.ui.control.Table
        SaveButton              matlab.ui.control.Button
        NumHydraEditFieldLabel  matlab.ui.control.Label
        NumHydraEditField       matlab.ui.control.EditField
        BckGrMaskSelection      matlab.ui.container.ButtonGroup
        AutoButton              matlab.ui.control.RadioButton
        ManualButton            matlab.ui.control.RadioButton
        MaskHydraButton         matlab.ui.control.Button
        TrackingTab             matlab.ui.container.Tab
    end


    properties (Access = public)
        TrackDir; % Description
        HydraROIs;
        HydraPositions;
        FirstFrameFig;
        CurrHydraROI;
        CurrHydraAnnotate;
        PossibleHydra;
        FirstFrame;
        BackgroundFrame;
        
    end
    
    methods (Access = private)
        
        function InitializeGUIStuff(app)
            app.FirstFrameFig = figure;
            app.PossibleHydra = 12;
            app.CurrHydraROI = 0;
            app.CurrHydraAnnotate = 0;
            app.HydraROIs = NaN(app.PossibleHydra,4);
            app.HydraPositions = NaN(app.PossibleHydra,6);
            app.UITableROI.Data = app.HydraROIs;
            app.UITableAnnotate.Data = app.HydraPositions;
            app.AnnotateHydraDropDown.Items = {'hydra0'};
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFrameButton
        function LoadFrameButtonPushed(app, event)
%         InitializeGUIStuff(app);
        
        
            TrackFolders = app.FilePathEditField.Value;
            [app.TrackDir] = getBehavioralTrackFileList({TrackFolders});
            FirstFile = app.TrackDir{1}.FileList{1};
            fullfilepath = strcat(TrackFolders, '\', FirstFile);    % get full filename
            
            
            firstvid = VideoReader(fullfilepath);
            firstframe= readFrame(firstvid);
            ax = app.FirstFrameFig;
            imshow(firstframe);
            title('First Frame)')
            datacursormode on;
            
            app.FirstFrame = firstframe;
            
            app.CurrHydraROI = 0;
            app.CurrHydraAnnotate = 0;
            app.HydraROIs = NaN(app.PossibleHydra,4);
            app.HydraPositions = NaN(app.PossibleHydra,6);
            app.UITableROI.Data = app.HydraROIs;
            app.UITableAnnotate.Data = app.HydraPositions;
            app.AnnotateHydraDropDown.Items = {'hydra0'};
            
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            app.CurrHydraROI = app.CurrHydraROI+1;
            
            ax = app.FirstFrameFig;
            title(strcat('First Frame Draw ROI #',num2str(app.CurrHydraROI)));
            
            r = drawrectangle(ax,"InteractionsAllowed","all");
            
            currROI = customWait(r);


            %             currROI = r1.Position;
            %[xmin, ymin, width, height]
            xPosLeft = round(currROI(1));
            xPosRight = round(currROI(1)+currROI(3));
            yPosBottom = round(currROI(2));
            yPosTop = round(currROI(2)+currROI(4));
            app.HydraROIs(app.CurrHydraROI,:) =[xPosLeft xPosRight yPosBottom yPosTop];
            
            app.UITableROI.Data = app.HydraROIs;
            r.Visible = 'off';
            rectangle('Position',[currROI],'LineWidth',2,'LineStyle','--')
            title(strcat('ROI completed for Hydra #', num2str(app.CurrHydraROI)))
            
            app.AnnotateHydraDropDown.Items{app.CurrHydraROI} = ['hydra' num2str(app.CurrHydraROI)];
            
            
            %
            function pos = customWait(hROI)
                l = addlistener(hROI,'ROIClicked',@clickCallback);
                uiwait;
                delete(l);
                pos = hROI.Position;
                
            end
            function clickCallback(~,evt)
                
                if strcmp(evt.SelectionType,'double')
                    uiresume;
                end
                
            end
            
        end

        % Display data changed function: UITableROI
        function UITableROIDisplayDataChanged(app, event)
            
            newDisplayData = app.UITableROI.DisplayData;


        end

        % Button pushed function: AnnotateButton
        function AnnotateButtonPushed(app, event)
            app.CurrHydraAnnotate = app.CurrHydraAnnotate+1;
            
            currPos = app.HydraROIs(app.CurrHydraAnnotate,:);
            
            xPosLeft = currPos(1);
            xPosRight = currPos(2);
            yPosBottom = currPos(3);
            yPosTop = currPos(4);
            
            
            ax = app.FirstFrameFig;
            firstframe = app.FirstFrame;
            croppedframe = firstframe(yPosBottom:yPosTop,xPosLeft:xPosRight,:);
            imshow(croppedframe)
            
            
            title(strcat('First Frame Select Foot #',num2str(app.CurrHydraAnnotate)));
            [x,y,button] = ginput(1);
            app.HydraPositions(app.CurrHydraAnnotate,1) = x;
            app.HydraPositions(app.CurrHydraAnnotate,2) = y;
            
            %             app.HydraFootPositionX(app.CurrHydraAnnotate) = x;
            %             app.HydraFootPositionY(app.CurrHydraAnnotate) = y;
            
            title(strcat('First Frame Select Center #',num2str(app.CurrHydraAnnotate)));
            [x,y,button] = ginput(1);
            app.HydraPositions(app.CurrHydraAnnotate,3) = x;
            app.HydraPositions(app.CurrHydraAnnotate,4) = y;
            %             pp.HydraCenterPositionX(app.CurrHydraAnnotate) = x;
            %             app.HydraCenterPositionY(app.CurrHydraAnnotate) = y;
            
            title(strcat('First Frame Select Mouth #', num2str(app.CurrHydraAnnotate)));
            [x,y,button] = ginput(1);
            app.HydraPositions(app.CurrHydraAnnotate,5) = x;
            app.HydraPositions(app.CurrHydraAnnotate,6) = y;
            %             pp.HydraMouthPositionX(app.CurrHydraAnnotate) = x;
            %             app.HydraMouthPositionY(app.CurrHydraAnnotate) = y;
            
            
            posmarkers = reshape(app.HydraPositions(app.CurrHydraAnnotate,:),2,[])';
            posmarkers(:,3) = 2;
            annotatedFrame = insertShape(croppedframe,'FilledCircle',posmarkers);
            imshow(annotatedFrame);
            title(strcat('Annotation completed for Hydra #', num2str(app.CurrHydraAnnotate)))
            
            
            app.UITableAnnotate.Data = app.HydraPositions;
            
            
        end

        % Value changed function: AnnotateHydraDropDown
        function AnnotateHydraDropDownValueChanged(app, event)
            value = app.AnnotateHydraDropDown.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if(app.CurrHydraAnnotate~=app.CurrHydraROI)
                disp(['Please check...Number of hydra ROI = ' num2str(app.CurrHydraROI),...
                    'but the number of hydra annotated = ', num2str(app.CurrHydraAnnotate)]);
            else
                for hh = 1:app.CurrHydraROI
                    % save roi around hydra chamber for cropping
                    Initialization.(['hydra' num2str(hh)]).xPosLeftChamber = app.HydraROIs(hh,1);
                    Initialization.(['hydra' num2str(hh)]).xPosRightChamber = app.HydraROIs(hh,2);
                    Initialization.(['hydra' num2str(hh)]).yPosBottomChamber = app.HydraROIs(hh,3);
                    Initialization.(['hydra' num2str(hh)]).yPosTopChamber = app.HydraROIs(hh,4);
                    
                    % save initial position of hydra for tracking
                    Initialization.(['hydra' num2str(hh)]).xFoot = app.HydraPositions(hh,1);
                    Initialization.(['hydra' num2str(hh)]).yFoot = app.HydraPositions(hh,2);
                    Initialization.(['hydra' num2str(hh)]).xCenter = app.HydraPositions(hh,3);
                    Initialization.(['hydra' num2str(hh)]).yCenter = app.HydraPositions(hh,4);
                    Initialization.(['hydra' num2str(hh)]).xMouth = app.HydraPositions(hh,5);
                    Initialization.(['hydra' num2str(hh)]).yMouth = app.HydraPositions(hh,6);
                    
                end
                
                
                Initialization.FirstFrame = app.FirstFrame;
                Initialization.BackgroundFrame = app.BackgroundFrame;


                outDir = strcat(app.FilePathEditField.Value,'\Analysis\');
                InitilizeFilePath = [outDir 'InitializationVals.mat'];
                mkdir(outDir);
                
                app.TrackDir{1}.NumHydra = app.CurrHydraROI;
                app.TrackDir{1}.OutputDir = outDir;
                app.TrackDir{1}.InitilizePath = InitilizeFilePath;
                TrackDir = app.TrackDir;


                save(InitilizeFilePath,"Initialization","TrackDir");
                disp('Saved initialization file with ROIs and hydra body annotated.')
            end
            
        end

        % Selection changed function: BckGrMaskSelection
        function BckGrMaskSelectionSelectionChanged(app, event)
            selectedButton = app.BckGrMaskSelection.SelectedObject;
            
        end

        % Button pushed function: MaskHydraButton
        function MaskHydraButtonPushed(app, event)
            
            grayFirstFrame = rgb2gray(app.FirstFrame);
            highContrastFirstFrame = imcomplement(grayFirstFrame);
            bckfr = grayFirstFrame;
            
            % If auto masking is selected, run this.
            if (app.AutoButton.Value==1)
                
                
                for hh = 1:app.CurrHydraROI
                   HydraChamberFr = highContrastFirstFrame(app.HydraROIs(hh,3):app.HydraROIs(hh,4),app.HydraROIs(hh,1):app.HydraROIs(hh,2));
                      
                   BWHydraChamber = imbinarize(HydraChamberFr,'adaptive');

                    HydraPosX = app.HydraPositions(hh,3);
                    HydraPosY = app.HydraPositions(hh,4);
                    
                    
                    hydraImg = bwselect(BWHydraChamber,HydraPosX,HydraPosY);
                    HydraReg = regionprops(hydraImg,'BoundingBox');
                    hydramask = zeros(size(BWHydraChamber),'logical');
                    hydramask(round(HydraReg.BoundingBox(2)):round(HydraReg.BoundingBox(2)+HydraReg.BoundingBox(4)),round(HydraReg.BoundingBox(1)):round(HydraReg.BoundingBox(1)+HydraReg.BoundingBox(3)))=1;
                    FrameMask = zeros(size(grayFirstFrame),'logical');
                    FrameMask(app.HydraROIs(hh,3):app.HydraROIs(hh,4),app.HydraROIs(hh,1):app.HydraROIs(hh,2)) = hydramask;
                    bckfr = regionfill(bckfr,FrameMask);
                    imshow(bckfr)
                end
                
                % if manual masking is selected run this
            elseif (app.ManualButton.Value ==1)
                imshow(app.FirstFrame)
                for hh = 1:app.CurrHydraROI
                    title(strcat('draw roi around hydra ',num2str(hh)));
                    hydra1mask = roipoly(bckfr);
                    bckfr = regionfill(bckfr,hydra1mask);
                end
            end
            
            imshow(bckfr)
            title('Background Frame');
            
            app.BackgroundFrame = bckfr;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadFrameButton = uibutton(app.UIFigure, 'push');
            app.LoadFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFrameButtonPushed, true);
            app.LoadFrameButton.Tooltip = {'Copy folder/directory where video files are into file path. Push Load frame to show the first frame.'};
            app.LoadFrameButton.Text = 'Load Frame';
            app.CopyfolderpathandclickLoadFrameLabel = uilabel(app.UIFigure);
            app.CopyfolderpathandclickLoadFrameLabel.Text = {'Copy folder path and click ''Load Frame''.'; 'New figure with first frame from a video should pop up.'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.DrawROIsPanel = uipanel(app.InitializationTab);
            app.DrawROIsPanel.Title = 'Draw ROIs';
            app.AddROIButton = uibutton(app.DrawROIsPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.FirstcopyfolderpathandclickLoadFrameLabel = uilabel(app.DrawROIsPanel);
            app.FirstcopyfolderpathandclickLoadFrameLabel.Text = {'To draw ROIs around individual Hydra chambers:'; '1. Click ''Add ROI'''; '2. Click and drag to draw square. Adjust as needed.'; '3. Double click to finalize ROI.'; '4. Repeat 1-3 for each Hydra.'; ''};
            app.AnnotateHydraPanel = uipanel(app.InitializationTab);
            app.AnnotateHydraPanel.Title = 'Annotate Hydra';
            app.HydraDropDownLabel = uilabel(app.AnnotateHydraPanel);
            app.HydraDropDownLabel.Text = 'Hydra';
            app.AnnotateHydraDropDown = uidropdown(app.AnnotateHydraPanel);
            app.AnnotateHydraDropDown.Items = {'Hydra 0'};
            app.AnnotateHydraDropDown.ValueChangedFcn = createCallbackFcn(app, @AnnotateHydraDropDownValueChanged, true);
            app.AnnotateHydraDropDown.Value = 'Hydra 0';
            app.AnnotateButton = uibutton(app.AnnotateHydraPanel, 'push');
            app.AnnotateButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateButtonPushed, true);
            app.AnnotateButton.Text = 'Annotate';
            app.Label = uilabel(app.AnnotateHydraPanel);
            app.Label.Text = {'To annotate Hydra body regions:'; '1. Click ''Annotate'''; '2. Click Hydra Foot.'; '3. Click Hydra body center.'; '4. Click Hydra Mouth.'; '5. Repeat 1-4 for each hydra.'};
            app.UITableROI = uitable(app.InitializationTab);
            app.UITableROI.ColumnName = {'xPos[1]'; 'xPos[2]'; 'yPos[1]'; 'yPos[2]'};
            app.UITableROI.DisplayDataChangedFcn = createCallbackFcn(app, @UITableROIDisplayDataChanged, true);
            app.UITableAnnotate = uitable(app.InitializationTab);
            app.UITableAnnotate.ColumnName = {'Foot_X'; 'Foot_Y'; 'Center_X'; 'Center_Y'; 'Mouth_X'; 'Mouth_Y'};
            app.SaveButton = uibutton(app.InitializationTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NumHydraEditFieldLabel = uilabel(app.InitializationTab);
            app.NumHydraEditFieldLabel.Text = 'Num Hydra';
            app.NumHydraEditField = uieditfield(app.InitializationTab, 'text');
            app.NumHydraEditField.Tooltip = {'Currently not doing used.'};
            app.NumHydraEditField.Value = '12';
            app.BckGrMaskSelection = uibuttongroup(app.InitializationTab);
            app.BckGrMaskSelection.SelectionChangedFcn = createCallbackFcn(app, @BckGrMaskSelectionSelectionChanged, true);
            app.BckGrMaskSelection.Title = 'Background Frame: Mask Around Hydra';
            app.AutoButton = uiradiobutton(app.BckGrMaskSelection);
            app.AutoButton.Text = 'Auto';
            app.ManualButton = uiradiobutton(app.BckGrMaskSelection);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.MaskHydraButton = uibutton(app.BckGrMaskSelection, 'push');
            app.MaskHydraButton.ButtonPushedFcn = createCallbackFcn(app, @MaskHydraButtonPushed, true);
            app.MaskHydraButton.Text = 'Mask Hydra';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hydra_ROI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end